classdef Coursework < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LeftPanel              matlab.ui.container.Panel
        StaticplotCheckBox     matlab.ui.control.CheckBox
        SphereButton           matlab.ui.control.Button
        StartAnimationButton   matlab.ui.control.Button
        OriginalCirclesButton  matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        ClickGraphtoStartConstructionLabel  matlab.ui.control.Label
        OpenButton             matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        LinesButton            matlab.ui.control.Button
        HideShowLabel          matlab.ui.control.Label
        CirclesButton          matlab.ui.control.Button
        PointsButton           matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
    end
    
    properties (Access = private)
        xPoints = [];
        yPoints = [];
        PointsPlot matlab.graphics.chart.primitive.Line
        LinesPlot matlab.graphics.chart.primitive.Line
        CirclesPlot matlab.graphics.chart.primitive.Line
        OriginalPlot matlab.graphics.chart.primitive.Line
        xCircle1 = '';
        yCircle1 = '';
        xCircle2 = '';
        yCircle2 = '';
        xCircle3 = '';
        yCircle3 = '';
        radius1 = '';
        radius2 = '';
        radius3 = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function savePoints(app)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            axis([-100 100 -100 100]);
            angles = linspace(0, 2*pi, 100);

            points = [];

            for i = 0:1
                hold on
                nodes = 1;
                [x,y] = ginput(nodes);
                app.xPoints = [app.xPoints, x];
                app.yPoints = [app.yPoints, y];
                plot(x,y,'--ys','LineWidth',2,'MarkerEdgeColor','c','MarkerFaceColor','c', 'MarkerSize',4,'Tag','Points');
                if i == 0
                    app.xCircle1 = x;
                    app.yCircle1 = y;
                else
                    xCircumference1 = x;
                    yCircumference1 = y;
                end
            end

            angles = linspace(0, 2*pi, 720);
            app.radius1 = sqrt((app.xCircle1-xCircumference1)^2 + (app.yCircle1-yCircumference1)^2);
            xCenter = app.xCircle1;
            yCenter = app.yCircle1;
            x = app.radius1 * cos(angles) + xCenter; 
            y = app.radius1 * sin(angles) + yCenter;
            % Plot circle.
            plot(x, y, 'b-', 'LineWidth', 2,'Tag','Circles');

            [app.xCircle2,app.yCircle2] = ginput(1);
            app.xPoints = [app.xPoints, app.xCircle2];
            app.yPoints = [app.yPoints, app.yCircle2];
            points = [points,[app.xCircle2;app.yCircle2]];
            plot(app.xCircle2,app.yCircle2,'--ys','LineWidth',2,'MarkerEdgeColor','c','MarkerFaceColor','c', 'MarkerSize',4,'Tag','Points');
            app.radius2 = radiusTangentialCircle(app.xCircle1,app.yCircle1,xCircumference1,yCircumference1,app.xCircle2,app.yCircle2);
            angles = linspace(0, 2*pi, 720);
            xCenter = app.xCircle2;
            yCenter = app.yCircle2;
            x = app.radius2 * cos(angles) + xCenter; 
            y = app.radius2 * sin(angles) + yCenter;
            % Plot circle.
            plot(x, y, 'b-', 'LineWidth', 2,'Tag','Circles');

            [xPoint,yPoint] = ginput(1);
            [xsnap1,ysnap1, select1] = closestPointsOnCircle(app.xCircle1,app.yCircle1,app.radius1,app.xCircle2,app.yCircle2,app.radius2,xPoint,yPoint);
            plot(xsnap1,ysnap1,'--ys','LineWidth',2,'MarkerEdgeColor','c','MarkerFaceColor','c', 'MarkerSize',4,'Tag','Points');
            points = [points,[xsnap1;ysnap1]];
            app.xPoints = [app.xPoints, xsnap1];
            app.yPoints = [app.yPoints, ysnap1];

            different = false;
            while different == false
                [xPoint,yPoint] = ginput(1);
                [xsnap2,ysnap2, select2] = closestPointsOnCircle(app.xCircle1,app.yCircle1,app.radius1,app.xCircle2,app.yCircle2,app.radius2,xPoint,yPoint);
                if select2 ~= select1
                    different = true;
                    plot(xsnap2,ysnap2,'--ys','LineWidth',2,'MarkerEdgeColor','c','MarkerFaceColor','c', 'MarkerSize',4,'Tag','Points');
                    points = [points,[xsnap2;ysnap2]];
                    app.xPoints = [app.xPoints, xsnap2];
                    app.yPoints = [app.yPoints, ysnap2];
                end
            end
            
            [m1,c1] = centreBisector(app.xCircle1,app.yCircle1,app.xCircle2,app.yCircle2);
            x = -100:100;
            y = m1*(x) + c1;
            plot(x,y,'--g','Linewidth',2,'Tag','Lines');

            [xOffBisector,yOffBisector] = ginput(1);
            [xOnBisector,yOnBistector] = closestPointBisector(m1,c1,xOffBisector,yOffBisector);
            plot(xOnBisector,yOnBistector,'--ys','LineWidth',2,'MarkerEdgeColor','c','MarkerFaceColor','c', 'MarkerSize',4,'Tag','Points');
            points = [points,[xOnBisector;yOnBistector]];
            app.xPoints = [app.xPoints, xOnBisector];
            app.yPoints = [app.yPoints, yOnBistector];

            [m2,c2] = centreBisector(xsnap1,ysnap1,xOnBisector,yOnBistector);
            x = -100:100;
            y = m2*(x) + c2;
            plot(x,y,'--g','Linewidth',2,'Tag','Lines');
            [m3,c3] = centreBisector(xsnap2,ysnap2,xOnBisector,yOnBistector);
            x = -100:100;
            y = m3*(x) + c3;
            plot(x,y,'--g','Linewidth',2,'Tag','Lines');

            p1 = [xsnap1,ysnap1];
            p2 = [xsnap2,ysnap2];
            p3 = [xOnBisector,yOnBistector];
            [app.xCircle3,app.yCircle3,app.radius3] = circleSolve(p1,p2,p3);
            xCenter = app.xCircle3;
            yCenter = app.yCircle3;
            x = app.radius3 * cos(angles) + xCenter; 
            y = app.radius3 * sin(angles) + yCenter;
            plot(x, y, 'b-', 'LineWidth', 2,'Tag','Circles');

            [x_intersections,y_intersections] = intersections2circles(app.radius1,app.xCircle1,app.yCircle1,app.radius3,app.xCircle3,app.yCircle3);
            plot(x_intersections,y_intersections,'--ys','LineWidth',2,'MarkerEdgeColor','m','MarkerFaceColor','m', 'MarkerSize',4,'Tag','Points');
              
                numIntersections = length(x_intersections);
                for i = i:numIntersections
                    points = [points,[x_intersections(i);y_intersections(i)]];
                end
                
            [x_intersections,y_intersections] = intersections2circles(app.radius2,app.xCircle2,app.yCircle2,app.radius3,app.xCircle3,app.yCircle3);
            plot(x_intersections,y_intersections,'--ys','LineWidth',2,'MarkerEdgeColor','m','MarkerFaceColor','m', 'MarkerSize',4,'Tag','Points');
                
                numIntersections = length(x_intersections);
                for i = i:numIntersections
                    points = [points,[x_intersections(i);y_intersections(i)]];
                end

            % Calculate transformation matrices
            [matrixT, matrixR, matrixCombined] = transformationMatrices(app.xCircle1, app.yCircle1, app.xCircle2, app.yCircle2, app.xCircle3, app.yCircle3);
            
            %Apply transformation matrix to stored points

            % Convert points to homogeneous coordinates
            points_homogeneous = [points; ones(1, length(points))];
            
            % Define original circles in homogeneous coordinates
            circle1 = [app.xCircle1; app.yCircle1; 1];
            circle2 = [app.xCircle2; app.yCircle2; 1];
            circle3 = [app.xCircle3; app.yCircle3; 1];
            
            % Apply the combined transformation matrix
            transformedCircle1 = matrixCombined * circle1;
            transformedCircle2 = matrixCombined * circle2;
            transformedCircle3 = matrixCombined * circle3;
            
            cla(app.UIAxes); % Clear existing plot
            
            disp("Translation Matrix:");
            disp(matrixT);
            
            disp("Rotation Matrix:");
            disp(matrixR);
            
            disp("Combined Transformation Matrix:");
            disp(matrixCombined);
            
            % Plot original circle 1
            xOrig1 = app.radius1 * cos(angles) + circle1(1);
            yOrig1 = app.radius1 * sin(angles) + circle1(2);
            app.OriginalPlot = [app.OriginalPlot, plot(app.UIAxes, xOrig1, yOrig1, 'g--', 'LineWidth', 1,'Tag','Original')]; % Original circle 1
            hold(app.UIAxes, 'on');
            
            % Plot original circle 2
            xOrig2 = app.radius2 * cos(angles) + circle2(1);
            yOrig2 = app.radius2 * sin(angles) + circle2(2);
            app.OriginalPlot = [app.OriginalPlot, plot(app.UIAxes, xOrig2, yOrig2, 'g--', 'LineWidth', 1,'Tag','Original')]; % Original circle 2
            
            % Plot original circle 3
            xOrig3 = app.radius3 * cos(angles) + circle3(1);
            yOrig3 = app.radius3 * sin(angles) + circle3(2);
            app.OriginalPlot = [app.OriginalPlot, plot(app.UIAxes, xOrig3, yOrig3, 'g--', 'LineWidth', 1,'Tag','Original')]; % Original circle 3
            
            % Plot transformed circle 1
            xTrans1 = app.radius1 * cos(angles) + transformedCircle1(1);
            yTrans1 = app.radius1 * sin(angles) + transformedCircle1(2);
            app.CirclesPlot = [app.CirclesPlot, plot(app.UIAxes, xTrans1, yTrans1, 'b-', 'LineWidth', 2,'Tag','Circles')];
            app.PointsPlot = [app.PointsPlot, plot(app.UIAxes, transformedCircle1(1), transformedCircle1(2), '--ys', 'LineWidth', 2, 'MarkerEdgeColor', 'm', 'MarkerFaceColor', 'm', 'MarkerSize', 4,'Tag','Points')];
            
            % Plot transformed circle 2
            xTrans2 = app.radius2 * cos(angles) + transformedCircle2(1);
            yTrans2 = app.radius2 * sin(angles) + transformedCircle2(2);
            app.CirclesPlot = [app.CirclesPlot, plot(app.UIAxes, xTrans2, yTrans2, 'b-', 'LineWidth', 2,'Tag','Circles')];
            app.PointsPlot = [app.PointsPlot, plot(app.UIAxes, transformedCircle2(1), transformedCircle2(2), '--ys', 'LineWidth', 2, 'MarkerEdgeColor', 'm', 'MarkerFaceColor', 'm', 'MarkerSize', 4,'Tag','Points')];
            
            % Plot centre bisector (1 & 2)
            [m1,c1] = centreBisector(transformedCircle1(1),transformedCircle1(2),transformedCircle2(1),transformedCircle2(2));
            x = -100:100;
            y = m1*(x) + c1;
            app.LinesPlot = [app.LinesPlot, plot(app.UIAxes,x,y,'--g','Linewidth',2,'Tag','Lines')];

            % Plot transformed circle 3
            xTrans3 = app.radius3 * cos(angles) + transformedCircle3(1);
            yTrans3 = app.radius3 * sin(angles) + transformedCircle3(2);
            app.CirclesPlot = [app.CirclesPlot, plot(app.UIAxes, xTrans3, yTrans3, 'b-', 'LineWidth', 2,'Tag','Circles')];
            app.PointsPlot = [app.PointsPlot, plot(app.UIAxes, transformedCircle3(1), transformedCircle3(2), '--ys', 'LineWidth', 2, 'MarkerEdgeColor', 'm', 'MarkerFaceColor', 'm', 'MarkerSize', 4,'Tag','Points')];
            
            % Plot centre bisector (1 & 3)
            [m1,c1] = centreBisector(transformedCircle1(1),transformedCircle1(2),transformedCircle3(1),transformedCircle3(2));
            x = -100:100;
            y = m1*(x) + c1;
            app.LinesPlot = [app.LinesPlot, plot(app.UIAxes,x,y,'--g','Linewidth',2,'Tag','Lines')];

            % Plot centre bisector (2 & 3)
            [m1,c1] = centreBisector(transformedCircle3(1),transformedCircle3(2),transformedCircle2(1),transformedCircle2(2));
            x = -100:100;
            y = m1*(x) + c1;
            app.LinesPlot = [app.LinesPlot, plot(app.UIAxes,x,y,'--g','Linewidth',2,'Tag','Lines')];
        
            % Transform and plot points
            transformed_points = matrixCombined * points_homogeneous;
            x_transformed = transformed_points(1, :);
            y_transformed = transformed_points(2, :);
            
            app.PointsPlot = [app.PointsPlot, plot(app.UIAxes, x_transformed, y_transformed, '--ys', 'LineWidth', 2, 'MarkerEdgeColor', 'm', 'MarkerFaceColor', 'm', 'MarkerSize', 4,'Tag','Points')];
            
            hold(app.UIAxes, 'off');

            app.UIFigure.HandleVisibility = fhv;

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Prompt the user to name the file and choose its location
            [file, path] = uiputfile('*.txt', 'Save Points');
            if file ~= 0
                % Combine x and y coordinates into a single matrix
                points = [app.xPoints; app.yPoints]';
                % Save to the specified text file
                saveFilePath = fullfile(path, file);
                fileID = fopen(saveFilePath, 'w');
                for i = 1:size(points, 1)
                    fprintf(fileID, '%.2f %.2f\n', points(i, 1), points(i, 2));
                end
                fclose(fileID);
                % Notify the user
                msgbox(['Points saved to ', saveFilePath], 'Save Successful');
                disp(['Points saved to ', saveFilePath]);
            end

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.txt', 'Text Files (*.txt)'}, 'Load Points');
            if isequal(fileName, 0) || isequal(filePath, 0)
                return; % User canceled load
            end
        
            % Load points from file
            fileID = fopen(fullfile(filePath, fileName), 'r');
            pointsData = fscanf(fileID, '%f %f', [2 Inf]);
            fclose(fileID);
        
            % Extract points
            app.xPoints = [];
            app.yPoints = [];
            app.xPoints = pointsData(1, :);
            app.yPoints = pointsData(2, :);
        
            % Clear the axes
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
        
            % Plot the loaded points
            for i = 1:length(app.xPoints)
                app.PointsPlot = [app.PointsPlot, plot(app.UIAxes, app.xPoints(i), app.yPoints(i), 'ro-', 'LineWidth', 2,'Tag','Points')];
            end

            angles = linspace(0, 2*pi, 100);
        
            % Plot Circle 1
            app.xCircle1 = app.xPoints(1);
            app.yCircle1 = app.yPoints(1);
            app.radius1 = sqrt((app.xCircle1-app.xPoints(2))^2 + (app.yCircle1-app.yPoints(2))^2);

            x = app.radius1 * cos(angles) + app.xCircle1; 
            y = app.radius1 * sin(angles) + app.yCircle1;
            % Plot circle.
            app.CirclesPlot = [app.CirclesPlot, plot(app.UIAxes, x, y, 'b-', 'LineWidth', 2,'Tag','Circles')];

            % Plot Circle 2
            app.xCircle2 = app.xPoints(3);
            app.yCircle2 = app.yPoints(3);
            app.radius2 = radiusTangentialCircle(app.xCircle1,app.yCircle1,app.xPoints(2),app.yPoints(2),app.xCircle2,app.yCircle2);

            x = app.radius2 * cos(angles) + app.xCircle2; 
            y = app.radius2 * sin(angles) + app.yCircle2;
            % Plot circle.
            app.CirclesPlot = [app.CirclesPlot, plot(app.UIAxes, x, y, 'b-', 'LineWidth', 2,'Tag','Circles')];

            % Plot centre bisector (1 & 2)
            [m1,c1] = centreBisector(app.xCircle1,app.yCircle1,app.xCircle2,app.yCircle2);
            x = -100:100;
            y = m1*(x) + c1;
            app.LinesPlot = [app.LinesPlot, plot(app.UIAxes,x,y,'--g','Linewidth',2,'Tag','Lines')];

            % Plot Circle 3
            p1 = [app.xPoints(4), app.yPoints(4)];
            p2 = [app.xPoints(5), app.yPoints(5)];
            p3 = [app.xPoints(6), app.yPoints(6)];
            [app.xCircle3, app.yCircle3, app.radius3] = circleSolve(p1, p2, p3);

            x = app.radius3 * cos(angles) + app.xCircle3; 
            y = app.radius3 * sin(angles) + app.yCircle3;
            % Plot circle.
            app.PointsPlot = [app.PointsPlot, plot(app.UIAxes, app.xCircle3, app.yCircle3, 'ro-', 'LineWidth', 2,'Tag','Points')];
            app.CirclesPlot = [app.CirclesPlot, plot(app.UIAxes, x, y, 'b-', 'LineWidth', 2,'Tag','Circles')];

            % Plot centre bisector (1 & 3)
            [m1,c1] = centreBisector(app.xCircle1,app.yCircle1,app.xCircle3,app.yCircle3);
            x = -100:100;
            y = m1*(x) + c1;
            app.LinesPlot = [app.LinesPlot, plot(app.UIAxes,x,y,'--g','Linewidth',2,'Tag','Lines')];

            % Plot centre bisector (2 & 3)
            [m1,c1] = centreBisector(app.xCircle3,app.yCircle3,app.xCircle2,app.yCircle2);
            x = -100:100;
            y = m1*(x) + c1;
            app.LinesPlot = [app.LinesPlot, plot(app.UIAxes,x,y,'--g','Linewidth',2,'Tag','Lines')];
        
            hold(app.UIAxes, 'off');

        end

        % Button pushed function: PointsButton
        function PointsButtonPushed(app, event)
            if strcmp(app.PointsPlot(1).Visible, 'on')
                for i = 1:length(app.PointsPlot)
                    app.PointsPlot(i).Visible = 'off';
                end
            else
                for i = 1:length(app.PointsPlot)
                    app.PointsPlot(i).Visible = 'on';
                end
            end
        end

        % Button pushed function: LinesButton
        function LinesButtonPushed(app, event)
            if strcmp(app.LinesPlot(1).Visible, 'on')
                for i = 1:length(app.LinesPlot)
                    app.LinesPlot(i).Visible = 'off';
                end
            else
                for i = 1:length(app.LinesPlot)
                    app.LinesPlot(i).Visible = 'on';
                end
            end
        end

        % Button pushed function: CirclesButton
        function CirclesButtonPushed(app, event)
            if strcmp(app.CirclesPlot(1).Visible, 'on')
                for i = 1:length(app.CirclesPlot)
                    app.CirclesPlot(i).Visible = 'off';
                end
            else
                for i = 1:length(app.CirclesPlot)
                    app.CirclesPlot(i).Visible = 'on';
                end
            end
        end

        % Button pushed function: OriginalCirclesButton
        function OriginalCirclesButtonPushed(app, event)
            if strcmp(app.OriginalPlot(1).Visible, 'on')
                for i = 1:length(app.OriginalPlot)
                    app.OriginalPlot(i).Visible = 'off';
                end
            else
                for i = 1:length(app.OriginalPlot)
                    app.OriginalPlot(i).Visible = 'on';
                end
            end
        end

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed(app, event)
            animate_circles(app.xCircle1,app.yCircle1,app.radius1,app.xCircle2,app.yCircle2,app.radius2,app.xCircle3,app.yCircle3,app.radius3)
        end

        % Button pushed function: SphereButton
        function SphereButtonPushed(app, event)
            if app.StaticplotCheckBox.Value == 1
                sphere_extension(app.xCircle1,app.yCircle1,app.radius1,app.xCircle2,app.yCircle2,app.radius2,app.xCircle3,app.yCircle3,app.radius3)
            else
                animateSphereAndCircles(app.xCircle1,app.yCircle1,app.radius1,app.xCircle2,app.yCircle2,app.radius2,app.xCircle3,app.yCircle3,app.radius3)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Circles')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.XTick = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.XTickLabel = {'-100'; '-90'; '-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PointsButton = uibutton(app.LeftPanel, 'push');
            app.PointsButton.ButtonPushedFcn = createCallbackFcn(app, @PointsButtonPushed, true);
            app.PointsButton.Text = 'Points';
            app.CirclesButton = uibutton(app.LeftPanel, 'push');
            app.CirclesButton.ButtonPushedFcn = createCallbackFcn(app, @CirclesButtonPushed, true);
            app.CirclesButton.Text = 'Circles';
            app.HideShowLabel = uilabel(app.LeftPanel);
            app.HideShowLabel.Text = 'Hide/Show:';
            app.LinesButton = uibutton(app.LeftPanel, 'push');
            app.LinesButton.ButtonPushedFcn = createCallbackFcn(app, @LinesButtonPushed, true);
            app.LinesButton.Text = 'Lines';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ClickGraphtoStartConstructionLabel = uilabel(app.LeftPanel);
            app.ClickGraphtoStartConstructionLabel.Text = 'Click Graph to Start Construction:';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.OriginalCirclesButton = uibutton(app.LeftPanel, 'push');
            app.OriginalCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalCirclesButtonPushed, true);
            app.OriginalCirclesButton.Text = 'Original Circles';
            app.StartAnimationButton = uibutton(app.LeftPanel, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed, true);
            app.StartAnimationButton.Text = 'Start Animation';
            app.SphereButton = uibutton(app.LeftPanel, 'push');
            app.SphereButton.ButtonPushedFcn = createCallbackFcn(app, @SphereButtonPushed, true);
            app.SphereButton.Text = 'Sphere';
            app.StaticplotCheckBox = uicheckbox(app.LeftPanel);
            app.StaticplotCheckBox.Text = 'Static plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Coursework

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @savePoints)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end